classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        MNafkhanZamzami13518132Label  matlab.ui.control.Label
        FadhilMRafi13518079Label      matlab.ui.control.Label
        Tugas4PengcitLabel            matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        Label                         matlab.ui.control.Label
        ResultLabel                   matlab.ui.control.Label
        InputPlateLabel               matlab.ui.control.Label
        APPLYButton                   matlab.ui.control.Button
        RESETButton                   matlab.ui.control.Button
        CarLicensingPlateRecognitionLabel  matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            
            [filename,filepath] = uigetfile({'*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           figure(app.UIFigure);
           fullname = [filepath, filename];
           app.Image.ImageSource = fullname;
           ImageFile = imread(fullname);
           app.UIAxes.Visible = 'on';
           app.UIAxes.XLim = [0 width(ImageFile)];
           app.UIAxes.YLim = [0 height(ImageFile)];
           imshow(ImageFile, 'Parent', app.UIAxes);
           app.Image.Visible = 'off';
           app.APPLYButton.Enable = 'on';
%            ChooseMethodButtonGroupSelectionChanged(app, event);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.Image.Visible = 'on';
            app.UIAxes.Visible = 'off';
            delete(app.UIAxes.Children);
            app.UIAxes2.Visible = 'off';
            delete(app.UIAxes2.Children);
            app.UIAxes3.Visible = 'off';
            delete(app.UIAxes3.Children);
            app.APPLYButton.Enable = 'off';
        end

        % Callback function
        function ChooseMethodButtonGroupSelectionChanged(app, event)
            if (app.ChooseMethodButtonGroup.SelectedObject.Text == "Low Pass Filter" || ...
                app.ChooseMethodButtonGroup.SelectedObject.Text == "High Pass Filter" ||...
                app.ChooseMethodButtonGroup.SelectedObject.Text == "Enhance Filter")
                app.FilterFunctionButtonGroup.Visible = 'on';
                app.CutoffFrequencySlider.Visible = 'on';
                app.CutoffFrequencySliderLabel.Visible ='on';
            else
                app.FilterFunctionButtonGroup.Visible = 'off';
                app.CutoffFrequencySlider.Visible = 'off';
                app.CutoffFrequencySliderLabel.Visible ='off';
            end
            if (~isempty(app.UIAxes.Children) && ...
                ~isempty(app.ChooseMethodButtonGroup.SelectedObject))
                if (app.ChooseMethodButtonGroup.SelectedObject.Text == "Low Pass Filter" || ...
                    app.ChooseMethodButtonGroup.SelectedObject.Text == "High Pass Filter")
                    app.FilterFunctionButtonGroup.Visible = 'on';
                    if (~isempty(app.FilterFunctionButtonGroup.SelectedObject))
                        app.APPLYButton.Enable = 'on';
                    end
                else
                    app.APPLYButton.Enable = 'on';
                end
            end

            selectedFilter = app.FilterFunctionButtonGroup.SelectedObject;
            selectedMethod = app.ChooseMethodButtonGroup.SelectedObject;

            RESETButtonPushed(app, event);

            if (selectedMethod.Text == "Remove Noise")
                app.FilterOrderSlider.Visible = 'off';
                app.FilterOrderSliderLabel.Visible = 'off';
                app.AlphaSlider.Visible = 'off';
                app.AlphaSliderLabel.Visible = 'off';
                app.Image.Visible = 'off';
                app.FruitImageChoiceDropDown.Visible = 'on';
                app.FruitImageChoiceDropDownLabel.Visible = 'on';
                FruitImageChoiceDropDownValueChanged(app, event);
            else
                app.FruitImageChoiceDropDown.Visible = 'off';
                app.FruitImageChoiceDropDownLabel.Visible = 'off';
                if (selectedMethod.Text == "Enhance Filter")
                    app.AlphaSlider.Visible = 'on';
                    app.AlphaSliderLabel.Visible = 'on';
                else
                    app.AlphaSlider.Visible = 'off';
                    app.AlphaSliderLabel.Visible = 'off';
                end
                if (selectedFilter.Text ~= "Butterworth")
                    app.FilterOrderSlider.Visible = 'off';
                    app.FilterOrderSliderLabel.Visible = 'off';
                else
                    app.FilterOrderSlider.Visible = 'on';
                    app.FilterOrderSliderLabel.Visible = 'on';
                end
            end
        end

        % Callback function
        function FilterFunctionButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterFunctionButtonGroup.SelectedObject;
            if (selectedButton.Text == "Butterworth")
                app.FilterOrderSlider.Visible = 'on';
                app.FilterOrderSliderLabel.Visible = 'on';
            else
                app.FilterOrderSlider.Visible = 'off';
                app.FilterOrderSliderLabel.Visible = 'off';
            end
        end

        % Button pushed function: APPLYButton
        function APPLYButtonPushed(app, event)
            image = imread(app.Image.ImageSource);
            
            edge = app.DropDown.Value;
            thresholding = app.DropDown_2.Value;
            if (edge == "Laplace")
                Jedge = Laplace(image);
            elseif (edge == "Gaussian")
                Jedge = Gaussian(image);
            elseif (edge == "LoG")
                Jedge = LoG(image);
            elseif (edge == "Robert")
                Jedge = Robert(image);
            elseif (edge == "Prewitt")
                Jedge = Prewitt(image);
            elseif (edge == "Sobel")
                Jedge = Sobel(image);
            elseif (edge == "Canny")
                Jedge = Canny(image);
            end
            app.UIAxes2.Visible = 'on';
            app.UIAxes2.XLim = [0 width(Jedge)];
            app.UIAxes2.YLim = [0 height(Jedge)];
            imshow(Jedge, 'Parent', app.UIAxes2);
            
            HJedge = Jedge;
            
            if (edge ~= "Canny" && thresholding == "Hystheresis")
                HJedge = Hystheresis(HJedge, 100, 50);
            end

            segmented = segment(image, HJedge);
            app.UIAxes3.Visible = 'on';
            app.UIAxes3.XLim = [0 width(segmented)];
            app.UIAxes3.YLim = [0 height(segmented)];
            imshow(segmented, 'Parent', app.UIAxes3);
        end

        % Callback function
        function FruitImageChoiceDropDownValueChanged(app, event)
            fullname = app.FruitImageChoiceDropDown.Value;
            figure(app.UIFigure);
            app.ImageFile = imread(fullname);
            app.UIAxes.Visible = 'on';
            app.UIAxes.XLim = [0 width(ImageFile)];
            app.UIAxes.YLim = [0 height(ImageFile)];
            imshow(ImageFile, 'Parent', app.UIAxes);
            app.Image.Visible = 'off';
            app.APPLYButton.Enable = 'on';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {548, 548};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {186, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Tugas4PengcitLabel = uilabel(app.LeftPanel);
            app.Tugas4PengcitLabel.Text = {'Tugas 4'; 'Pengcit'};
            app.FadhilMRafi13518079Label = uilabel(app.LeftPanel);
            app.FadhilMRafi13518079Label.Text = {'Fadhil M. Rafi'''; '13518079'};
            app.MNafkhanZamzami13518132Label = uilabel(app.LeftPanel);
            app.MNafkhanZamzami13518132Label.Text = {'M. Nafkhan '; 'Zamzami'; '13518132'};
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes2 = uiaxes(app.GridLayout2);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes = uiaxes(app.GridLayout2);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.CarLicensingPlateRecognitionLabel = uilabel(app.GridLayout2);
            app.CarLicensingPlateRecognitionLabel.Text = 'Car Licensing Plate Recognition';
            app.RESETButton = uibutton(app.GridLayout2, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.APPLYButton = uibutton(app.GridLayout2, 'push');
            app.APPLYButton.ButtonPushedFcn = createCallbackFcn(app, @APPLYButtonPushed, true);
            app.APPLYButton.Enable = 'off';
            app.APPLYButton.Text = 'APPLY';
            app.InputPlateLabel = uilabel(app.GridLayout2);
            app.InputPlateLabel.Text = 'Input Plate';
            app.ResultLabel = uilabel(app.GridLayout2);
            app.ResultLabel.Text = 'Result';
            app.Label = uilabel(app.GridLayout2);
            app.Label.Text = '" "';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end